classdef Module1GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Module1UIFigure               matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        MainViewsGrid                 matlab.ui.container.GridLayout
        taskPanel                     matlab.ui.container.GridLayout
        GridLayout5                   matlab.ui.container.GridLayout
        GridLayout6                   matlab.ui.container.GridLayout
        OpenSTLButton                 matlab.ui.control.Button
        ClearSTLButton                matlab.ui.control.Button
        SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel  matlab.ui.control.Label
        SelectedFileNameLabel         matlab.ui.control.Label
        ApplyTransformstoModelButton  matlab.ui.control.Button
        ClearTransformsButton         matlab.ui.control.Button
        GridLayout12                  matlab.ui.container.GridLayout
        GridLayout13                  matlab.ui.container.GridLayout
        Image4                        matlab.ui.control.Image
        ScaleResizeLabel              matlab.ui.control.Label
        ScaleSpinnerPanel             matlab.ui.container.Panel
        ScaleLabel_2                  matlab.ui.control.Label
        ScaleSpinner                  matlab.ui.control.Spinner
        TranslationGridPanel          matlab.ui.container.GridLayout
        TranslateHeader               matlab.ui.container.GridLayout
        TranslateMoveLabel            matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        TranslatethisModelCheckBox    matlab.ui.control.CheckBox
        TranslationSpinnerPanel       matlab.ui.container.Panel
        TranslatexmmSpinnerLabel      matlab.ui.control.Label
        TranslateXSpinner             matlab.ui.control.Spinner
        TranslateymmSpinnerLabel      matlab.ui.control.Label
        TranslateYSpinner             matlab.ui.control.Spinner
        TranslatezmmSpinnerLabel      matlab.ui.control.Label
        TranslateZSpinner             matlab.ui.control.Spinner
        RotationGridPanel             matlab.ui.container.GridLayout
        RotateHeader                  matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        RotateLabel_2                 matlab.ui.control.Label
        RotatethisModelCheckBox       matlab.ui.control.CheckBox
        RotationSpinnerPanel          matlab.ui.container.Panel
        RotatexdegreesSpinnerLabel    matlab.ui.control.Label
        RotatexdegreesSpinner         matlab.ui.control.Spinner
        RotateydegreesSpinnerLabel    matlab.ui.control.Label
        RotateydegreesSpinner         matlab.ui.control.Spinner
        RotatezdegreesSpinnerLabel    matlab.ui.control.Label
        RotatezdegreesSpinner         matlab.ui.control.Spinner
        GridLayout9                   matlab.ui.container.GridLayout
        TransformHelpButton           matlab.ui.control.Button
        SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_2  matlab.ui.control.Label
        ImportSTLLabel                matlab.ui.control.Label
        TransformModelLabel           matlab.ui.control.Label
        previewAxis                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        file;                           % The STL File opened by the application
        importedSTLModel;               % A copy of the model to restore if modification made in the transpose tab are reset
        stlModel = [];                  % The Triangulated Model stored by MATLAB
        modelImported = 0;
        
        plotLimits;
        
    end
    
    methods (Access = private)
        
        % Updates the Plot with the triangulation from the STL File
        function updateImportPreview(app)
            
            if app.modelImported
                
                % Clear plot first
                cla(app.previewAxis);
                
                % Then, draw
                view(app.previewAxis,3);
                patch(app.previewAxis, 'Faces', app.stlModel.ConnectivityList, 'Vertices', app.stlModel.Points, 'EdgeColor','black','FaceColor','none');
                
            end
            
        end
        
        % Updates the Transform Buttons based on the current selection,
        % such that the Transform Buttons only enable if a transform option
        % is selected
        function updateTransformMasterButtons(app, event)
            
            % If an STL File Exists
            if app.modelImported
                
                rotateChecked = app.RotatethisModelCheckBox.Value;
                transformChecked = app.TranslatethisModelCheckBox.Value;
                scaleChanged = (app.ScaleSpinner.Value ~= 100);
                
                % If any of the checkboxes are checked, then enable the apply
                % button
                if (rotateChecked || transformChecked || scaleChanged)
                    
                    app.ApplyTransformstoModelButton.Enable = true;
                    
                % If none of the checkboxes are checked, then reset model and
                % disable    
                else
                    
                    app.ApplyTransformstoModelButton.Enable = false;
                    app.ClearTransformsButton.Enable = false;          
                    ClearTransformsButtonPushed(app, event);
                    
                end
                
            end


        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenSTLButton
        function OpenSTLButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module1UIFigure, 'Title','Please Wait','Message','Waiting for user file selection...','Indeterminate','on');
            
            try
                % Attempt to open the STL File
                fileTypes = {'*.stl', 'STL Files (.stl)'};
                [app.file, path] = uigetfile(fileTypes, 'Open STL File...');
                
                % Add PATH to file to MATLAB's path
                addpath(path);
                
                % Open the STL File and convert to triangulation data
                progressBar.Message = "Opening STL file";
                app.stlModel = importSTL(app.file);
                app.importedSTLModel = app.stlModel;
                app.modelImported = 1;
                
                % Move STL such that it lies on the positive plane
                app.stlModel = stlTransform(app.stlModel, 0, 0, 0, 0, 0, 0, 100);
                
                % Update the model displayed
                progressBar.Message = "Computing Data";
                app.previewAxis.Visible = true;
                updateImportPreview(app);
                
                % Update Label
                app.SelectedFileNameLabel.Text = app.file;
                
                % Update the Clear and Next buttons
                app.ClearSTLButton.Enable = true;
                app.OpenSTLButton.Enable = false;
                
            catch exception
                % Ignore Exception and return
                errordlg(exception.message)
                close(progressBar);
                return
            end
            
            % Close progress bar
            close(progressBar);
            
        end

        % Button pushed function: ClearSTLButton
        function ClearSTLButtonPushed(app, event)
            
            % Clear the references
            app.stlModel = [];
            app.file = [];
            app.modelImported = 0;
            
            % Reset the Selected File Label
            app.SelectedFileNameLabel.Text = "No File Selected";
            
            % Update the Clear and Next buttons
            app.ClearSTLButton.Enable = false;
            app.OpenSTLButton.Enable = true;
            
            % Reset the model and hide the figure
            app.previewAxis.Visible = false;
            cla(app.previewAxis);
                        
        end

        % Button pushed function: ApplyTransformstoModelButton
        function ApplyTransformstoModelButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module1UIFigure, 'Title','Please Wait','Message','Applying transforms','Indeterminate','on');
            
            % Retrieve the Spinner Values for Theta
            thetaX = app.RotatexdegreesSpinner.Value;
            thetaY = app.RotateydegreesSpinner.Value;
            thetaZ = app.RotatezdegreesSpinner.Value;
            
            % Retrieve the Spinner Values for Transforms
            dX = app.TranslateXSpinner.Value;
            dY = app.TranslateYSpinner.Value;
            dZ = app.TranslateZSpinner.Value;
            
            % Retrieve the Spinner Value for the Scale
            scale = app.ScaleSpinner.Value;
            
            % Pass to function
            app.stlModel = stlTransform(app.importedSTLModel, thetaX, thetaY, thetaZ, dX, dY, dZ, scale);
            
            % Update plot
            app.updateImportPreview();
            
            % Enable the reset button
            app.ClearTransformsButton.Enable = true;
            
            % Close progress bar
            close(progressBar);
            
        end

        % Button pushed function: ClearTransformsButton
        function ClearTransformsButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module1UIFigure, 'Title','Please Wait','Message','Reverting back to imported model','Indeterminate','on');
            
            % Reset to intial model
            app.stlModel = app.importedSTLModel;
            
            % Update Plot
            app.updateImportPreview();
            
            % Disable the reset button
            app.ClearTransformsButton.Enable = false;
            
            % Clear all checkboxes
            app.RotatethisModelCheckBox.Value = 0;
            app.TranslatethisModelCheckBox.Value = 0;
            app.TranslatethisModelCheckBox.Enable = 1;
            
            % Clear all spinners
            app.RotatexdegreesSpinner.Enable = false;
            app.RotateydegreesSpinner.Enable = false;
            app.RotatezdegreesSpinner.Enable = false;
            
            app.RotatexdegreesSpinner.Value = 0;
            app.RotateydegreesSpinner.Value = 0;
            app.RotatezdegreesSpinner.Value = 0;
            
            app.TranslateXSpinner.Enable = false;
            app.TranslateYSpinner.Enable = false;
            app.TranslateZSpinner.Enable = false;
            
            app.TranslateXSpinner.Value = 0;
            app.TranslateYSpinner.Value = 0;
            app.TranslateZSpinner.Value = 0;
            
            app.ScaleSpinner.Value = 100.0;
            
            % Disable the Apply Button
            app.ApplyTransformstoModelButton.Enable = false;

            % Close progress bar
            close(progressBar);
            
        end

        % Value changed function: RotatethisModelCheckBox
        function RotatethisModelCheckBoxValueChanged(app, event)
            
            value = app.RotatethisModelCheckBox.Value;
            
            % Enable or disable the spinners
            app.RotatexdegreesSpinner.Enable = value;
            app.RotateydegreesSpinner.Enable = value;
            app.RotatezdegreesSpinner.Enable = value;
            
            % If disabled, reset the values in the Spinners
            if value == 0
                app.RotatexdegreesSpinner.Value = 0;
                app.RotateydegreesSpinner.Value = 0;
                app.RotatezdegreesSpinner.Value = 0;
            end
            
            % Update the master Transform Buttons
            updateTransformMasterButtons(app, event);
            
        end

        % Value changed function: TranslatethisModelCheckBox
        function TranslatethisModelCheckBoxValueChanged(app, event)
            
            value = app.TranslatethisModelCheckBox.Value;
            
            % Enable or disable the spinners
            app.TranslateXSpinner.Enable = value;
            app.TranslateYSpinner.Enable = value;
            app.TranslateZSpinner.Enable = value;
            
            % If disabled, reset the values in the Spinners
            if value == 0
                app.TranslateXSpinner.Value = 0;
                app.TranslateYSpinner.Value = 0;
                app.TranslateZSpinner.Value = 0;
            end
            
            % Update the master Transform Buttons
            updateTransformMasterButtons(app, event);
            
        end

        % Value changed function: ScaleSpinner
        function ScaleSpinnerValueChanged(app, event)
            updateTransformMasterButtons(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Module1UIFigure = uifigure('Visible', 'off');
            app.Module1UIFigure.Name = 'Module 1: STL Viewer';
            app.Module1UIFigure.Icon = 'img_stl_16px.png';
            app.GridLayout = uigridlayout(app.Module1UIFigure);
            app.MainViewsGrid = uigridlayout(app.GridLayout);
            app.taskPanel = uigridlayout(app.MainViewsGrid);
            app.GridLayout5 = uigridlayout(app.taskPanel);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.OpenSTLButton = uibutton(app.GridLayout6, 'push');
            app.OpenSTLButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSTLButtonPushed, true);
            app.OpenSTLButton.Icon = 'img_open_22px.png';
            app.OpenSTLButton.Text = 'Open STL...';
            app.ClearSTLButton = uibutton(app.GridLayout6, 'push');
            app.ClearSTLButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSTLButtonPushed, true);
            app.ClearSTLButton.Icon = 'img_erase_22px.png';
            app.ClearSTLButton.Enable = 'off';
            app.ClearSTLButton.Text = 'Clear STL...';
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel = uilabel(app.GridLayout5);
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel.Text = 'Select "Open STL..." and browse to the STL file to be imported & sliced:';
            app.SelectedFileNameLabel = uilabel(app.GridLayout5);
            app.SelectedFileNameLabel.Text = 'No File Selected';
            app.ApplyTransformstoModelButton = uibutton(app.GridLayout5, 'push');
            app.ApplyTransformstoModelButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTransformstoModelButtonPushed, true);
            app.ApplyTransformstoModelButton.Icon = 'img_transform_22px.png';
            app.ApplyTransformstoModelButton.Enable = 'off';
            app.ApplyTransformstoModelButton.Text = 'Apply Transforms to Model';
            app.ClearTransformsButton = uibutton(app.GridLayout5, 'push');
            app.ClearTransformsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTransformsButtonPushed, true);
            app.ClearTransformsButton.Icon = 'img_erase_22px.png';
            app.ClearTransformsButton.Enable = 'off';
            app.ClearTransformsButton.Text = 'Clear Transforms';
            app.GridLayout12 = uigridlayout(app.GridLayout5);
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.Image4 = uiimage(app.GridLayout13);
            app.Image4.ImageSource = 'img_scale_22px.png';
            app.ScaleResizeLabel = uilabel(app.GridLayout13);
            app.ScaleResizeLabel.Text = 'Scale (Resize)';
            app.ScaleSpinnerPanel = uipanel(app.GridLayout12);
            app.ScaleLabel_2 = uilabel(app.ScaleSpinnerPanel);
            app.ScaleLabel_2.Text = 'Scale (%)';
            app.ScaleSpinner = uispinner(app.ScaleSpinnerPanel);
            app.ScaleSpinner.Step = 0.1;
            app.ScaleSpinner.Limits = [0.1 Inf];
            app.ScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @ScaleSpinnerValueChanged, true);
            app.ScaleSpinner.Value = 100;
            app.TranslationGridPanel = uigridlayout(app.GridLayout5);
            app.TranslateHeader = uigridlayout(app.TranslationGridPanel);
            app.TranslateMoveLabel = uilabel(app.TranslateHeader);
            app.TranslateMoveLabel.Text = 'Translate (Move)';
            app.Image2 = uiimage(app.TranslateHeader);
            app.Image2.ImageSource = 'img_translate_22px.png';
            app.TranslatethisModelCheckBox = uicheckbox(app.TranslationGridPanel);
            app.TranslatethisModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @TranslatethisModelCheckBoxValueChanged, true);
            app.TranslatethisModelCheckBox.Text = 'Translate this Model';
            app.TranslationSpinnerPanel = uipanel(app.TranslationGridPanel);
            app.TranslatexmmSpinnerLabel = uilabel(app.TranslationSpinnerPanel);
            app.TranslatexmmSpinnerLabel.Text = 'Translate x (mm)';
            app.TranslateXSpinner = uispinner(app.TranslationSpinnerPanel);
            app.TranslateXSpinner.Step = 0.1;
            app.TranslateXSpinner.Limits = [0 Inf];
            app.TranslateXSpinner.Enable = 'off';
            app.TranslateymmSpinnerLabel = uilabel(app.TranslationSpinnerPanel);
            app.TranslateymmSpinnerLabel.Text = 'Translate y (mm)';
            app.TranslateYSpinner = uispinner(app.TranslationSpinnerPanel);
            app.TranslateYSpinner.Step = 0.1;
            app.TranslateYSpinner.Limits = [0 Inf];
            app.TranslateYSpinner.Enable = 'off';
            app.TranslatezmmSpinnerLabel = uilabel(app.TranslationSpinnerPanel);
            app.TranslatezmmSpinnerLabel.Text = 'Translate z (mm)';
            app.TranslateZSpinner = uispinner(app.TranslationSpinnerPanel);
            app.TranslateZSpinner.Step = 0.1;
            app.TranslateZSpinner.Limits = [0 Inf];
            app.TranslateZSpinner.Enable = 'off';
            app.RotationGridPanel = uigridlayout(app.GridLayout5);
            app.RotateHeader = uigridlayout(app.RotationGridPanel);
            app.Image = uiimage(app.RotateHeader);
            app.Image.ImageSource = 'img_transformRotate_22px.png';
            app.RotateLabel_2 = uilabel(app.RotateHeader);
            app.RotateLabel_2.Text = 'Rotate';
            app.RotatethisModelCheckBox = uicheckbox(app.RotationGridPanel);
            app.RotatethisModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotatethisModelCheckBoxValueChanged, true);
            app.RotatethisModelCheckBox.Text = 'Rotate this Model';
            app.RotationSpinnerPanel = uipanel(app.RotationGridPanel);
            app.RotatexdegreesSpinnerLabel = uilabel(app.RotationSpinnerPanel);
            app.RotatexdegreesSpinnerLabel.Text = 'Rotate x (degrees)';
            app.RotatexdegreesSpinner = uispinner(app.RotationSpinnerPanel);
            app.RotatexdegreesSpinner.Step = 0.1;
            app.RotatexdegreesSpinner.Limits = [-360 360];
            app.RotatexdegreesSpinner.Enable = 'off';
            app.RotateydegreesSpinnerLabel = uilabel(app.RotationSpinnerPanel);
            app.RotateydegreesSpinnerLabel.Text = 'Rotate y (degrees)';
            app.RotateydegreesSpinner = uispinner(app.RotationSpinnerPanel);
            app.RotateydegreesSpinner.Step = 0.1;
            app.RotateydegreesSpinner.Limits = [-360 360];
            app.RotateydegreesSpinner.Enable = 'off';
            app.RotatezdegreesSpinnerLabel = uilabel(app.RotationSpinnerPanel);
            app.RotatezdegreesSpinnerLabel.Text = 'Rotate z (degrees)';
            app.RotatezdegreesSpinner = uispinner(app.RotationSpinnerPanel);
            app.RotatezdegreesSpinner.Step = 0.1;
            app.RotatezdegreesSpinner.Limits = [-360 360];
            app.RotatezdegreesSpinner.Enable = 'off';
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.TransformHelpButton = uibutton(app.GridLayout9, 'push');
            app.TransformHelpButton.Icon = 'img_help_22px.png';
            app.TransformHelpButton.Text = '';
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_2 = uilabel(app.GridLayout9);
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_2.Text = 'Transform the model using the toolset below as desired:';
            app.ImportSTLLabel = uilabel(app.GridLayout5);
            app.ImportSTLLabel.Text = 'Import STL';
            app.TransformModelLabel = uilabel(app.GridLayout5);
            app.TransformModelLabel.Text = 'Transform Model';
            app.previewAxis = uiaxes(app.MainViewsGrid);
            xlabel(app.previewAxis, 'X')
            ylabel(app.previewAxis, 'Y')
            zlabel(app.previewAxis, 'Z')
            app.previewAxis.Visible = 'off';
            % Show the figure after all components are created
            app.Module1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module1GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Module1UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Module1UIFigure)
        end
    end
end