classdef Module4GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Module2AdaptiveFlatLayerSlicerUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        StepButtonGrid                  matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        Step1Button                     matlab.ui.control.Button
        Step2Button                     matlab.ui.control.Button
        Step3Button                     matlab.ui.control.Button
        Step4Button                     matlab.ui.control.Button
        MainViewsGrid                   matlab.ui.container.GridLayout
        taskPanel                       matlab.ui.container.GridLayout
        NoModelLabel                    matlab.ui.control.Label
        previewAxis                     matlab.ui.control.UIAxes
        TransformPanel                  matlab.ui.container.Panel
        TransformPanelGridLayout        matlab.ui.container.GridLayout
        GotoStep3ConfigureButton        matlab.ui.control.Button
        RotationGridPanel               matlab.ui.container.GridLayout
        RotateHeader                    matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        RotateLabel_2                   matlab.ui.control.Label
        RotatethisModelCheckBox         matlab.ui.control.CheckBox
        RotationSpinnerPanel            matlab.ui.container.Panel
        RotatexdegreesSpinnerLabel      matlab.ui.control.Label
        RotatexdegreesSpinner           matlab.ui.control.Spinner
        RotateydegreesSpinnerLabel      matlab.ui.control.Label
        RotateydegreesSpinner           matlab.ui.control.Spinner
        RotatezdegreesSpinnerLabel      matlab.ui.control.Label
        RotatezdegreesSpinner           matlab.ui.control.Spinner
        ClearTransformsButton           matlab.ui.control.Button
        ApplyTransformstoModelButton    matlab.ui.control.Button
        TranslationGridPanel            matlab.ui.container.GridLayout
        TranslateHeader                 matlab.ui.container.GridLayout
        TranslateMoveLabel              matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        TranslatethisModelCheckBox      matlab.ui.control.CheckBox
        TranslationSpinnerPanel         matlab.ui.container.Panel
        TranslatexmmSpinnerLabel        matlab.ui.control.Label
        TranslateXSpinner               matlab.ui.control.Spinner
        TranslateymmSpinnerLabel        matlab.ui.control.Label
        TranslateYSpinner               matlab.ui.control.Spinner
        TranslatezmmSpinnerLabel        matlab.ui.control.Label
        TranslateZSpinner               matlab.ui.control.Spinner
        GridLayout12                    matlab.ui.container.GridLayout
        GridLayout13                    matlab.ui.container.GridLayout
        Image4                          matlab.ui.control.Image
        ScaleResizeLabel                matlab.ui.control.Label
        ScaleSpinnerPanel               matlab.ui.container.Panel
        ScaleLabel_2                    matlab.ui.control.Label
        ScaleSpinner                    matlab.ui.control.Spinner
        Image11                         matlab.ui.control.Image
        SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_2  matlab.ui.control.Label
        ConfigurePanel                  matlab.ui.container.Panel
        GridLayout5_3                   matlab.ui.container.GridLayout
        SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_5  matlab.ui.control.Label
        GridLayout14                    matlab.ui.container.GridLayout
        GridLayout15                    matlab.ui.container.GridLayout
        PathSelectionLabel              matlab.ui.control.Label
        Image7                          matlab.ui.control.Image
        Label_2                         matlab.ui.control.Label
        PathSelectionRadioGroup         matlab.ui.container.ButtonGroup
        FullRasterPathButton            matlab.ui.control.RadioButton
        EdgePathOnlyButton              matlab.ui.control.RadioButton
        Image10                         matlab.ui.control.Image
        AdaptiveSlicerLayout            matlab.ui.container.GridLayout
        GridLayout18                    matlab.ui.container.GridLayout
        SlicerSelectionLabel            matlab.ui.control.Label
        Image6                          matlab.ui.control.Image
        SlicerSelectionRadioGroup       matlab.ui.container.ButtonGroup
        UniformSlicingButton            matlab.ui.control.RadioButton
        AdaptiveSlicingButton           matlab.ui.control.RadioButton
        Label                           matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        LayerThicknessmmSpinnerLabel    matlab.ui.control.Label
        LayerThicknessmmSpinner         matlab.ui.control.Spinner
        Image9                          matlab.ui.control.Image
        GotoStep4SliceButton            matlab.ui.control.Button
        SlicePanel                      matlab.ui.container.Panel
        GridLayout5_4                   matlab.ui.container.GridLayout
        GridLayout6_4                   matlab.ui.container.GridLayout
        ResetViewButton                 matlab.ui.control.Button
        ExportDataButton                matlab.ui.control.Button
        SelectedFileNameLabel_4         matlab.ui.control.Label
        SliceModelButton                matlab.ui.control.Button
        GridLayout16                    matlab.ui.container.GridLayout
        GridLayout17                    matlab.ui.container.GridLayout
        ExploreLayersLabel              matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        Panel_2                         matlab.ui.container.Panel
        LayerLabel                      matlab.ui.control.Label
        LayerViewSlider                 matlab.ui.control.Slider
        LayerViewNumberLabel            matlab.ui.control.Label
        IsolateLayerinPreviewCheckBox   matlab.ui.control.CheckBox
        SliceHeroLabel                  matlab.ui.control.Label
        AnimationGrid                   matlab.ui.container.GridLayout
        AnimationHero                   matlab.ui.container.GridLayout
        AnimationTitle                  matlab.ui.control.Label
        AnimationImg                    matlab.ui.control.Image
        AnimationSpeedPanel             matlab.ui.container.Panel
        StepSpeedsecLabel               matlab.ui.control.Label
        AnimationSpeedSpinner           matlab.ui.control.Spinner
        AnimateButton                   matlab.ui.control.Button
        AnimationOptionRadioControlGroup  matlab.ui.container.ButtonGroup
        AnimateSelectedSliceButton      matlab.ui.control.RadioButton
        AnimateuptoSelectedSliceButton  matlab.ui.control.RadioButton
        AnimateentireModelButton        matlab.ui.control.RadioButton
        DefinePanel                     matlab.ui.container.Panel
        DefinePanelLayout               matlab.ui.container.GridLayout
        DefinethepropertiesoftheBezierCurvetogenerateLabel  matlab.ui.control.Label
        GotoStep2TransformButton        matlab.ui.control.Button
        AutoGenXYRadios                 matlab.ui.container.GridLayout
        GridLayout20                    matlab.ui.container.GridLayout
        Image8                          matlab.ui.control.Image
        GenerateUniformXYPointsLabel    matlab.ui.control.Label
        AutoGenXYRadioGroup             matlab.ui.container.ButtonGroup
        AutoGenerateButton              matlab.ui.control.RadioButton
        ManuallyDefineBxByButton        matlab.ui.control.RadioButton
        SquareMatrixSizeSpinnerPanel    matlab.ui.container.Panel
        SquareMatrixSizeSpinnerLabel    matlab.ui.control.Label
        SquareMatrixSizeSpinner         matlab.ui.control.Spinner
        bZTableLayout                   matlab.ui.container.GridLayout
        GridLayout21                    matlab.ui.container.GridLayout
        Image12                         matlab.ui.control.Image
        DefineBzLabel                   matlab.ui.control.Label
        bZTable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        
        file                                        % The STL File opened by the application
        importedSTLModel                            % A copy of the model to restore if modification made in the transpose tab are reset
        stlModel                                    % The Triangulated Model stored by MATLAB
        layerThickness = 0.2;                       % Layer thickness defined by user (defaults to 0.2mm)
        
        uniformSliceGenerator;                      % The Slice Generator Objects used to generate the paths
        adaptiveSliceGenerator;
        
        slicerPath = [];                            % The Slicer Path generated by the generators
        plotLimits;                                 % The limits for the plot
        
        defaultAnimationSpeed = 0.5;                % Default animation speed
        isAnimating = 0;                            % Flag which indicates whether a layer animation is running
        
        primaryAccent = [0.02, 0.62, 0.87];         % GUI Properties
        activeStepButtonColour = [0.99, 0.99, 0.99];
        inactiveStepButtonColour = [0.85, 0.85, 0.85];
        tableBackgroundColour = [0.95, 0.95, 0.95];
        
        bDefaultColumnWidth = 40;
        
        bX = zeros(2);
        bY = zeros(2);
        bZ = zeros(2);

    end
    
    methods (Access = private)
        
        function updateImportPreview(app)
        % Updates the Plot with the triangulation from the STL File  
        
            % Clear plot.then draw the STL Model using the Path Class
            cla(app.previewAxis);
            view(app.previewAxis,3);
            patch(app.previewAxis, 'Faces', app.stlModel.ConnectivityList, 'Vertices', app.stlModel.Points, 'EdgeColor','black','FaceColor','none');
            
            % Set limits for plot
            xlim(app.previewAxis, app.plotLimits(1,:));
            ylim(app.previewAxis, app.plotLimits(2,:));
            zlim(app.previewAxis, app.plotLimits(3,:));
              
        end
        
        function updateSlicerPathPreview(app, maxLayer, isolateLayer)
        % Update the Plot with a preview of the slice paths for the print
        
            % Clear plot first
            cla(app.previewAxis);

            % Get all z-Heights from output Slicer Data
            [~, zHeights] = groupcounts(app.slicerPath(:,3));
            
            % Set the maxLayer if input > 0, else default to maxHeight
            if maxLayer <= 0
                maxLayer = height(zHeights);
            end
            
            % If isolating layer, only draw the paths associated with slice
            if isolateLayer
                
                % Determine the current layer Z
                currZ = zHeights(maxLayer);
                zPlotPoints = app.slicerPath(app.slicerPath(:,3) == currZ,:);
                
                % Draw each path separately
                for j = 1:max(zPlotPoints(:,4))
                    pathPlotPoints = zPlotPoints(zPlotPoints(:,4) == j,:);
                    plot3(app.previewAxis,pathPlotPoints(:,1), pathPlotPoints(:,2), pathPlotPoints(:,3),'-k','LineWidth',1);
                    hold(app.previewAxis, 'on');
                end
                
            else
                
                % Then, for each slice draw the path(s)
                for i = 1:maxLayer
        
                    % Determine applicable z-axis slicer paths
                    currZ = zHeights(i);
                    zPlotPoints = app.slicerPath(app.slicerPath(:,3) == currZ,:);
                    
                    % Draw each path separately
                    for j = 1:max(zPlotPoints(:,4))
                        pathPlotPoints = zPlotPoints(zPlotPoints(:,4) == j,:);
                        plot3(app.previewAxis,pathPlotPoints(:,1), pathPlotPoints(:,2), pathPlotPoints(:,3),'-k','LineWidth',1);
                        hold(app.previewAxis, 'on');
                    end
                
                end
                
            end
            
            % Set limits for plot
            xlim(app.previewAxis, app.plotLimits(1,:));
            ylim(app.previewAxis, app.plotLimits(2,:));
            zlim(app.previewAxis, app.plotLimits(3,:));
            
            % Disable the hold on the axis
            hold(app.previewAxis, 'off');
          
        end
        
        function animateSlicerPathPreview(app, layerToAnimate, delay, isolateLayer)
        % Animate the layer on the plot
        
            % Update Animating Flag to 1
            app.isAnimating = 1;
            
            % Disable Buttons
            app.updateSliceViewControls(0);
            app.AnimateButton.Enable = 1;
            app.SliceModelButton.Enable = 0;
            app.updateStepControls(0);
            
            % Match the Isolate Layer in Preview Checkbox with the desired
            % option
            app.IsolateLayerinPreviewCheckBox.Value = isolateLayer;
            
            % Clear plot first
            cla(app.previewAxis);
            
            % Change the button to show animation is in progress
            app.AnimateButton.Text = "Stop Animating";
            app.AnimateButton.Icon = 'img_stopAnimating_22px.png';
            
            % Get all z-Heights from output Slicer Data
            [~, zHeights] = groupcounts(app.slicerPath(:,3));
            numOfSlices = height(zHeights);
            
            % Determine Layers to Animation
            minLayer = 1;
            
            % If Isolating animation to a Single Layer, then set the min
            % and max layers
            if isolateLayer
                minLayer = layerToAnimate;
                maxLayer = layerToAnimate;
            elseif layerToAnimate <= 0
                maxLayer = numOfSlices;
            else
                maxLayer = layerToAnimate;
            end
            
            % Define a reference to the plot object
            plot = plot3(app.previewAxis,0,0,0);
            
            % Then, for each slice draw the path(s)
            for i = minLayer:maxLayer
    
                % Determine applicable z-axis slicer paths
                currZ = zHeights(i);
                zPlotPoints = app.slicerPath(app.slicerPath(:,3) == currZ,:);
                
                % Draw each path separately
                for j = 1:max(zPlotPoints(:,4))
                    
                    pathPlotPoints = zPlotPoints(zPlotPoints(:,4) == j,:);
                    
                     % Draw each point separately
                    for k = 1:height(pathPlotPoints) - 1
                        
                        plot.Color = 'k';
                        plot.LineWidth = 1;
                        plot = plot3(app.previewAxis,pathPlotPoints(k:k+1,1), pathPlotPoints(k:k+1,2), pathPlotPoints(k:k+1,3),'-b','LineWidth',2);
                        hold(app.previewAxis, 'on');
                        pause(delay);
                        
                        % If the Stop Animation button is selected, draw the
                        % normal preview, enable the reset button, and break
                        if app.isAnimating == 0
                            break;
                        end
                            
                    end

                    if app.isAnimating == 0
                        break;
                    end
                    
                end
                
                if app.isAnimating == 0
                    break;
                end
            
            end
            
            % Disable the hold on the axis and pause to give plot chance to
            % render.
            plot.Color = 'k';
            plot.LineWidth = 1;
            hold(app.previewAxis, 'off');
            pause(0.5);
            
            % If the animation has been stopped by the user, redraw initial
            % plot before continuing.
            if app.isAnimating == 0
                updateSlicerPathPreview(app, layerToAnimate, app.IsolateLayerinPreviewCheckBox.Value);
            end
                    
            % Re-enable the Buttons
            app.updateSliceViewControls(1);
            app.SliceModelButton.Enable = 1;
            app.updateStepControls(4);
            
            % Change the button to show animation has completed
            app.AnimateButton.Text = "Animate...";
            app.AnimateButton.Icon = 'img_animate_22px.png';
            
            % Update Animating Flag to 0
            app.isAnimating = 0;
          
        end
        
        % Update the Step Button Controls at the top of the window based on
        % the steps currently available
        function updateStepControls(app, stepNumber)
            app.Step1Button.Enable = (stepNumber >= 1);
            app.Step2Button.Enable = (stepNumber >= 2);
            app.Step3Button.Enable = (stepNumber >= 3);
            app.Step4Button.Enable = (stepNumber >= 4);
        end
        
        % Updates the Step Button Controls at the top of the window all to
        % the inactive colour
        function updateStepControlColour(app)
            app.Step1Button.BackgroundColor = app.inactiveStepButtonColour;
            app.Step2Button.BackgroundColor = app.inactiveStepButtonColour;
            app.Step3Button.BackgroundColor = app.inactiveStepButtonColour;
            app.Step4Button.BackgroundColor = app.inactiveStepButtonColour;
        end
    
        % Updates the Side Panel to remove the children when transitioning
        % to another panel
        function removeChildPanel(app)
            app.DefinePanel.Parent = [];
            app.TransformPanel.Parent = [];
            app.ConfigurePanel.Parent = [];
            app.SlicePanel.Parent = [];
        end
        
        % Update the controls on the Slice Panel
        function updateSliceViewControls(app, enable)
            
            % If enable is -1, set to a boolean
            if enable < 0
                enable = height(app.slicerPath) > 0;
            end
            
            % Enable or disable the tools based on if a Slicer Path has
            % been generated
            
            app.ExportDataButton.Enable = enable;
            app.ResetViewButton.Enable = enable;
            
            % Update the controls in the Layer View Control
            app.LayerViewSlider.Enable = enable;
            app.IsolateLayerinPreviewCheckBox.Enable = enable;
            
            % Update the controls in the Animation Panel
            app.AnimateButton.Enable = enable;
            app.AnimationSpeedSpinner.Enable = enable;
            app.AnimateentireModelButton.Enable = enable;
            app.AnimateuptoSelectedSliceButton.Enable = enable;
            app.AnimateSelectedSliceButton.Enable = enable;
            
        end
        
        % Updates the Transform Buttons on the Tranform Step
        function updateTransformMasterButtons(app, event)
            
            % Check what options have been checked
            rotateChecked = app.RotatethisModelCheckBox.Value;
            transformChecked = app.TranslatethisModelCheckBox.Value;
            scaleChanged = (app.ScaleSpinner.Value ~= 100);
            
            % If any of the checkboxes are checked, then enable the apply
            % button
            if (rotateChecked || transformChecked || scaleChanged)
                app.ApplyTransformstoModelButton.Enable = true;
                
            % If none of the checkboxes are checked, then reset model and
            % disable    
            else
                app.ApplyTransformstoModelButton.Enable = false;
                app.ClearTransformsButton.Enable = false;                
                ClearTransformsButtonPushed(app, event);
            end
            
        end
    
        % Updates the plot limits which bound the default viewing region of
        % the 3D Object. Enforces a 1:1:1 scaling across axis'.
        function updatePlotLimits(app)
            
            % Find the size of each plane of the model
            xSize = max(app.stlModel.Points(:,1)) - min(app.stlModel.Points(:,1));
            ySize = max(app.stlModel.Points(:,2)) - min(app.stlModel.Points(:,2));
            zSize = max(app.stlModel.Points(:,3)) - min(app.stlModel.Points(:,3));
            
            % The largest size will determine the size of each plane
            bufferZone = 5;
            planeSize = max(max(xSize, ySize), zSize) + 2 * bufferZone;
            planeBuff = planeSize / 2;
            
            % Find the center of the model
            xCenter = min(app.stlModel.Points(:,1)) + (xSize / 2);
            yCenter = min(app.stlModel.Points(:,2)) + (ySize / 2);
            
            % Update the Plot Limits
            % Note that for the model to sit on the Z axis, the Z should
            % start at 0 and end at planeSize
            app.plotLimits = [xCenter - planeBuff, xCenter + planeBuff;
                                yCenter - planeBuff, yCenter + planeBuff;
                                0, planeSize];

        end
        
        % Update the dimensions of the Bz Table depending on the Spinner
        % Value
        function updateBTableDimensions(app)
            dimensions = app.SquareMatrixSizeSpinner.Value;
            app.bZ = zeros(dimensions);
            columnWidths = ones(1, dimensions) * app.bDefaultColumnWidth;
            app.bZTable.Data = app.bZ;
            app.bZTable.RowName = 'numbered';
            app.bZTable.ColumnName = 'numbered';
            app.bZTable.ColumnWidth = num2cell(columnWidths);
            app.bZTable.ColumnEditable = true;
            app.bZTable.BackgroundColor = app.tableBackgroundColour;
        end
        
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dummy)
            app.DefinePanel.Parent = app.taskPanel;
            updateBTableDimensions(app);
        end

        % Callback function
        function OpenSTLButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module2AdaptiveFlatLayerSlicerUIFigure, 'Title','Please Wait','Message','Waiting for user file selection...','Indeterminate','on');
            
            try
                % Attempt to open the STL File
                fileTypes = {'*.stl', 'STL Files (.stl)'};
                [app.file, path] = uigetfile(fileTypes, 'Open STL File...');
                
                % Add PATH to file to MATLAB's path
                addpath(path);
                
                % Open the STL File and convert to triangulation data
                progressBar.Message = "Importing STL file (this might take a moment)";
                if app.AutoGenerateButton.Value == 1
                    app.stlModel = stlread(app.file);
                else
                    app.stlModel = importSTL(app.file);
                end
                 
                % Move STL such that it lies on the positive plane, then
                % create a copy to store at the Originally Imported Model.
                % Calculate the new Plot Limits
                progressBar.Message = "Fitting model to Plane";
                app.stlModel = stlTransform(app.stlModel, 0, 0, 0, 0, 0, 0, 100);
                app.importedSTLModel = app.stlModel;
                app.updatePlotLimits();
                
                % Update the model displayed
                progressBar.Message = "Drawing slicer path preview (this might take a moment)";
                app.previewAxis.Visible = true;
                app.NoModelLabel.Visible = false;
                updateImportPreview(app);
                
                % Update Labels
                progressBar.Message = "Updating UI (this might take a moment)";
                app.SelectedFileNameLabel.Text = app.file;
                details = sprintf('%s\n%s',"Verticies: " + height(app.stlModel.Points),"Elements: " + height(app.stlModel.ConnectivityList));
                app.DetailsLabel.Text = details;
                
                % Update the Clear and Next buttons
                app.ClearSTLButton.Enable = true;
                app.OpenSTLButton.Enable = false;
                app.GotoStep2TransformButton.Enable = true;
                
                % Update the Step Control Buttons
                app.updateStepControls(4);
                
            catch exception
                % Ignore Exception and return
                errordlg(exception.message)
                close(progressBar);
                return
            end
            
            % Close progress bar
            close(progressBar);
            
        end

        % Callback function
        function ClearSTLButtonPushed(app, event)
            
            % Clear the references
            app.stlModel = [];
            app.file = [];
            app.slicerPath = [];
            
            % Reset the Selected File Label
            app.SelectedFileNameLabel.Text = "No File Selected";
            
             % Update Labels
            app.DetailsLabel.Text = ' ';
            
            % Update the Clear and Next buttons
            app.ClearSTLButton.Enable = false;
            app.OpenSTLButton.Enable = true;
            app.GotoStep2TransformButton.Enable = false;
            
            % Reset the Step Control Buttons
            app.updateStepControls(1);
            
            % Reset the model and hide the figure
            app.previewAxis.Visible = false;
            app.NoModelLabel.Visible = true;
            cla(app.previewAxis);
                        
        end

        % Button pushed function: Step1Button
        function Step1ButtonPushed(app, event)
            
            % Set the Active Button Colour
            app.updateStepControlColour();
            app.Step1Button.BackgroundColor = app.activeStepButtonColour;
            
            % If moving from the Slicer View, update Preview with STL
            if (app.SlicePanel.Parent == app.taskPanel)
                app.updateImportPreview();
            end
            
            % Update the Side Panel
            app.removeChildPanel();
            app.DefinePanel.Parent = app.taskPanel;
            
        end

        % Button pushed function: Step2Button
        function Step2ButtonPushed(app, event)
            
            % Set the Active Button Colour
            app.updateStepControlColour();
            app.Step2Button.BackgroundColor = app.activeStepButtonColour;
            
            % If moving from the Slicer View, update Preview with STL
            if (app.SlicePanel.Parent == app.taskPanel)
                app.updateImportPreview();
            end
            
            % Update the Side Panel
            app.removeChildPanel();
            app.TransformPanel.Parent = app.taskPanel;
            
        end

        % Button pushed function: Step3Button
        function Step3ButtonPushed(app, event)
            
            % Set the Active Button Colour
            app.updateStepControlColour();
            app.Step3Button.BackgroundColor = app.activeStepButtonColour;
            
            % If moving from the Slicer View, update Preview with STL
            if (app.SlicePanel.Parent == app.taskPanel)
                app.updateImportPreview();
            end
            
            % Update the Side Panel
            app.removeChildPanel();
            app.ConfigurePanel.Parent = app.taskPanel;
            
        end

        % Button pushed function: Step4Button
        function Step4ButtonPushed(app, event)
            
            % Set the Active Button Colour
            app.updateStepControlColour();
            app.Step4Button.BackgroundColor = app.activeStepButtonColour;
            
            % Update the Side Panel
            app.removeChildPanel();
            app.SlicePanel.Parent = app.taskPanel;
            app.updateSliceViewControls(-1);
            
            maxZ = app.LayerViewSlider.Value;
            
            % If a slicer path is available, show it in the preview.
            % Otherwise, clear
            if height(app.slicerPath) == 0
                cla(app.previewAxis);
            else
                LayerViewSliderValueChanged(app,event);
            end
            
        end

        % Button pushed function: GotoStep2TransformButton
        function GotoStep2TransformButtonPushed(app, event)
            Step2ButtonPushed(app,event);
        end

        % Button pushed function: GotoStep3ConfigureButton
        function GotoStep3ConfigureButtonPushed(app, event)
            Step3ButtonPushed(app,event);
        end

        % Button pushed function: GotoStep4SliceButton
        function GotoStep4SliceButtonPushed(app, event)
            Step4ButtonPushed(app,event);
        end

        % Value changed function: LayerThicknessmmSpinner
        function LayerThicknessmmSpinnerValueChanged(app, event)
            app.layerThickness = app.LayerThicknessmmSpinner.Value;
        end

        % Button pushed function: SliceModelButton
        function SliceModelButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module2AdaptiveFlatLayerSlicerUIFigure, 'Title','Please Wait','Message','Slicing (this might take a moment)','Indeterminate','on');
            
            % If using a uniform slicer, then create the new object and
            % slice
            if (app.AdaptiveSlicingButton.Value == 0)
                app.uniformSliceGenerator = FlatUniformSliceGenerator(app.stlModel, app.layerThickness);
                app.adaptiveSliceGenerator = [];
                app.slicerPath = app.uniformSliceGenerator.getSlicePath();
                
            else
                app.uniformSliceGenerator = [];
                app.adaptiveSliceGenerator = FlatAdaptiveSliceGenerator(app.stlModel, app.layerThickness);
                app.slicerPath = app.adaptiveSliceGenerator.getSlicePath();
            end
            
            % If the Full Raster Path is desired, then run the rasteriser
            if (app.FullRasterPathButton.Value == 1)
                
            end
            
            % Update the plot with a preview of the slicer paths
            progressBar.Message = "Drawing slicer path preview (this might take a moment)";
            app.updatePlotLimits();
            app.updateSlicerPathPreview(-1, 0);
            
            % Update the Slicer Preview Bar (with a pause for updating the
            % UI)
            progressBar.Message = "Updating UI (this might take a moment)";
            pause(1);
            [~, zHeights] = groupcounts(app.slicerPath(:,3));
            maxLayer = height(zHeights);
            app.LayerViewSlider.Limits = [1, maxLayer];
            app.LayerViewSlider.Value = maxLayer;
            app.LayerViewNumberLabel.Text = string(maxLayer);
            app.updateSliceViewControls(-1);
            
            % Close progress bar
            close(progressBar);
            
        end

        % Button pushed function: ApplyTransformstoModelButton
        function ApplyTransformstoModelButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module2AdaptiveFlatLayerSlicerUIFigure, 'Title','Please Wait','Message','Applying transforms','Indeterminate','on');
            
            % Clear any existing Slicer Path
            app.slicerPath = [];
            
            % Retrieve the Spinner Values for the Angle
            thetaX = app.RotatexdegreesSpinner.Value;
            thetaY = app.RotateydegreesSpinner.Value;
            thetaZ = app.RotatezdegreesSpinner.Value;
            
            % Retrieve the Spinner Values for the Translate Value
            dX = app.TranslateXSpinner.Value;
            dY = app.TranslateYSpinner.Value;
            dZ = app.TranslateZSpinner.Value;
            
            % Retrieve the Spinner Values for the Scale Value
            scale = app.ScaleSpinner.Value;
            
            % Pass to function
            app.stlModel = stlTransform(app.importedSTLModel, thetaX, thetaY, thetaZ, dX, dY, dZ, scale);
            
            % Calculate the new Plot Limits
            app.updatePlotLimits();
                
            % Update plot with the returned model
            app.updateImportPreview();
            
            % Enable the reset button
            app.ClearTransformsButton.Enable = true;
            
            % Wait for window re-draw to complete and close progress bar
            pause(0.5);
            close(progressBar);
            
        end

        % Button pushed function: ClearTransformsButton
        function ClearTransformsButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module2AdaptiveFlatLayerSlicerUIFigure, 'Title','Please Wait','Message','Reverting back to imported model','Indeterminate','on');
            
            % Reset to intial model
            app.stlModel = app.importedSTLModel;
            
            % Clear any existing Slicer Path
            app.slicerPath = [];
            app.updatePlotLimits();
            
            % Update Plot
            app.updateImportPreview();
            
            % Disable the reset button
            app.ClearTransformsButton.Enable = false;
            
            % Clear all checkboxes
            app.RotatethisModelCheckBox.Value = 0;
            app.TranslatethisModelCheckBox.Value = 0;
            app.TranslatethisModelCheckBox.Enable = 1;
            
            % Clear all spinners
            app.RotatexdegreesSpinner.Enable = false;
            app.RotateydegreesSpinner.Enable = false;
            app.RotatezdegreesSpinner.Enable = false;
            
            app.RotatexdegreesSpinner.Value = 0;
            app.RotateydegreesSpinner.Value = 0;
            app.RotatezdegreesSpinner.Value = 0;
            
            app.TranslateXSpinner.Enable = false;
            app.TranslateYSpinner.Enable = false;
            app.TranslateZSpinner.Enable = false;
            
            app.TranslateXSpinner.Value = 0;
            app.TranslateYSpinner.Value = 0;
            app.TranslateZSpinner.Value = 0;
            
            app.ScaleSpinner.Value = 100.0;
            
            % Disable the Apply Button
            app.ApplyTransformstoModelButton.Enable = false;

            % Close progress bar
            close(progressBar);
            
        end

        % Value changed function: RotatethisModelCheckBox
        function RotatethisModelCheckBoxValueChanged(app, event)
            value = app.RotatethisModelCheckBox.Value;
            
            if value == 0
                
                app.RotatexdegreesSpinner.Enable = false;
                app.RotateydegreesSpinner.Enable = false;
                app.RotatezdegreesSpinner.Enable = false;
                
                app.RotatexdegreesSpinner.Value = 0;
                app.RotateydegreesSpinner.Value = 0;
                app.RotatezdegreesSpinner.Value = 0;
                
            else
                
                app.RotatexdegreesSpinner.Enable = true;
                app.RotateydegreesSpinner.Enable = true;
                app.RotatezdegreesSpinner.Enable = true;
                
            end
            
            updateTransformMasterButtons(app, event);
            
        end

        % Value changed function: TranslatethisModelCheckBox
        function TranslatethisModelCheckBoxValueChanged(app, event)
            value = app.TranslatethisModelCheckBox.Value;
            
            if value == 0
                
                app.TranslateXSpinner.Enable = false;
                app.TranslateYSpinner.Enable = false;
                app.TranslateZSpinner.Enable = false;
                
                app.TranslateXSpinner.Value = 0;
                app.TranslateYSpinner.Value = 0;
                app.TranslateZSpinner.Value = 0;
                
            else
                
                app.TranslateXSpinner.Enable = true;
                app.TranslateYSpinner.Enable = true;
                app.TranslateZSpinner.Enable = true;
                
            end
            
            updateTransformMasterButtons(app, event);
        end

        % Value changed function: ScaleSpinner
        function ScaleSpinnerValueChanged(app, event)
            updateTransformMasterButtons(app, event);
        end

        % Value changed function: LayerViewSlider
        function LayerViewSliderValueChanged(app, event)
            
            % Disable the slider
            app.LayerViewSlider.Enable = false;
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module2AdaptiveFlatLayerSlicerUIFigure, 'Title','Please Wait','Message','Updating slicer path preview (this might take a moment)','Indeterminate','on');
                        
            % Get the value and re-draw
            value = round(app.LayerViewSlider.Value);
            isolate = app.IsolateLayerinPreviewCheckBox.Value;
            app.updateSlicerPathPreview(value, isolate);
            
            % Close Progress Bar
            pause(1);
            close(progressBar);
            
            % Re-enable the slider
            app.LayerViewSlider.Enable = true;
            
        end

        % Value changing function: LayerViewSlider
        function LayerViewSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.LayerViewNumberLabel.Text = string(changingValue);
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            
            % If an animiation is currently running, set the flag to stop
            % it
            if (app.isAnimating)
                app.AnimateButton.Text = "Stopping...";
                app.isAnimating = 0;
                
            % Otherwise, start animation
            else
                
                if app.AnimateuptoSelectedSliceButton.Value == 1
                    isolate = 0;
                    selectedLayer = round(app.LayerViewSlider.Value);
                elseif app.AnimateSelectedSliceButton.Value == 1
                    isolate = 1;
                    selectedLayer = round(app.LayerViewSlider.Value);
                else
                    isolate = 0;
                    selectedLayer = -1;
                end
                
                animationSpeed = app.AnimationSpeedSpinner.Value;
                app.animateSlicerPathPreview(selectedLayer, animationSpeed, isolate);
                
            end
            
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            
            % Show progress bar
            progressBar = uiprogressdlg(app.Module2AdaptiveFlatLayerSlicerUIFigure, 'Title','Please Wait','Message','Waiting for user to select file location...','Indeterminate','on');
            
            try
              fileTypes = {'*.csv', 'CSV File'};
                [fileName, filePath] = uiputfile(fileTypes, 'Export to CSV File...');
                addpath(filePath);
                progressBar.Message = "Exporting data";
                writematrix(app.slicerPath, filePath + "\" + fileName);
                helpdlg("The file has been successfully exported.");
                close(progressBar);
            catch exception
                errordlg("There was an issue exporting a .csv file: " + exception.message);
                close(progressBar);
                return;
            end
            
        end

        % Button pushed function: ResetViewButton
        function ResetViewButtonPushed(app, event)
            
            % Reset the Values of the Slider and Checkbox
            app.IsolateLayerinPreviewCheckBox.Value = 0;
            app.LayerViewSlider.Value = app.LayerViewSlider.Limits(1,2);
            app.AnimationSpeedSpinner.Value = app.defaultAnimationSpeed;
            app.LayerViewNumberLabel.Text = string(app.LayerViewSlider.Value);
            
            % Use the Callback for the Slider Change to redraw the plot
            app.LayerViewSliderValueChanged(event);
            
        end

        % Value changed function: IsolateLayerinPreviewCheckBox
        function IsolateLayerinPreviewCheckBoxValueChanged(app, event)
            
            % Call the Layer Slider Changed Callback to update the plot
            app.LayerViewSliderValueChanged(event);
            
        end

        % Selection changed function: SlicerSelectionRadioGroup
        function SlicerSelectionRadioGroupSelectionChanged(app, event)
            app.slicerPath = [];
            app.LayerThicknessmmSpinner.Enable = app.UniformSlicingButton.Value;
        end

        % Value changed function: SquareMatrixSizeSpinner
        function SquareMatrixSizeSpinnerValueChanged(app, event)
            value = app.SquareMatrixSizeSpinner.Value;
            app.updateBTableDimensions();
        end

        % Cell edit callback: bZTable
        function bZTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if ~isnan(newData) && isnumeric(newData)
                app.bZ(indices) = newData;
            else
                app.bZTable.Data = app.bZ;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Module2AdaptiveFlatLayerSlicerUIFigure = uifigure('Visible', 'off');
            app.Module2AdaptiveFlatLayerSlicerUIFigure.Name = 'Module 2: Adaptive Flat Layer Slicer';
            app.Module2AdaptiveFlatLayerSlicerUIFigure.Icon = 'img_slice_32px.png';
            app.GridLayout = uigridlayout(app.Module2AdaptiveFlatLayerSlicerUIFigure);
            app.StepButtonGrid = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.StepButtonGrid);
            app.Step1Button = uibutton(app.GridLayout4, 'push');
            app.Step1Button.ButtonPushedFcn = createCallbackFcn(app, @Step1ButtonPushed, true);
            app.Step1Button.Icon = 'img_importSTL_32px.png';
            app.Step1Button.Text = '1. Define';
            app.Step2Button = uibutton(app.GridLayout4, 'push');
            app.Step2Button.ButtonPushedFcn = createCallbackFcn(app, @Step2ButtonPushed, true);
            app.Step2Button.Icon = 'img_transform_32px.png';
            app.Step2Button.Enable = 'off';
            app.Step2Button.Text = '2. Transform';
            app.Step3Button = uibutton(app.GridLayout4, 'push');
            app.Step3Button.ButtonPushedFcn = createCallbackFcn(app, @Step3ButtonPushed, true);
            app.Step3Button.Icon = 'img_configure_32px.png';
            app.Step3Button.Enable = 'off';
            app.Step3Button.Text = '3. Configure';
            app.Step4Button = uibutton(app.GridLayout4, 'push');
            app.Step4Button.ButtonPushedFcn = createCallbackFcn(app, @Step4ButtonPushed, true);
            app.Step4Button.Icon = 'img_slice_32px.png';
            app.Step4Button.Enable = 'off';
            app.Step4Button.Text = '4. Slice';
            app.MainViewsGrid = uigridlayout(app.GridLayout);
            app.taskPanel = uigridlayout(app.MainViewsGrid);
            app.NoModelLabel = uilabel(app.MainViewsGrid);
            app.NoModelLabel.Text = {'No Model Loaded'; 'Select "Open STL..." to import an STL into the workspace.'};
            app.previewAxis = uiaxes(app.MainViewsGrid);
            xlabel(app.previewAxis, 'X')
            ylabel(app.previewAxis, 'Y')
            zlabel(app.previewAxis, 'Z')
            app.previewAxis.Visible = 'off';
            app.TransformPanel = uipanel(app.Module2AdaptiveFlatLayerSlicerUIFigure);
            app.TransformPanel.Title = 'Transform';
            app.TransformPanelGridLayout = uigridlayout(app.TransformPanel);
            app.GotoStep3ConfigureButton = uibutton(app.TransformPanelGridLayout, 'push');
            app.GotoStep3ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @GotoStep3ConfigureButtonPushed, true);
            app.GotoStep3ConfigureButton.Icon = 'img_arrowRight_16px.png';
            app.GotoStep3ConfigureButton.Text = 'Go to Step 3: Configure';
            app.RotationGridPanel = uigridlayout(app.TransformPanelGridLayout);
            app.RotateHeader = uigridlayout(app.RotationGridPanel);
            app.Image = uiimage(app.RotateHeader);
            app.Image.ImageSource = 'img_transformRotate_22px.png';
            app.RotateLabel_2 = uilabel(app.RotateHeader);
            app.RotateLabel_2.Text = 'Rotate';
            app.RotatethisModelCheckBox = uicheckbox(app.RotationGridPanel);
            app.RotatethisModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotatethisModelCheckBoxValueChanged, true);
            app.RotatethisModelCheckBox.Text = 'Rotate this Model';
            app.RotationSpinnerPanel = uipanel(app.RotationGridPanel);
            app.RotatexdegreesSpinnerLabel = uilabel(app.RotationSpinnerPanel);
            app.RotatexdegreesSpinnerLabel.Text = 'Rotate x (degrees)';
            app.RotatexdegreesSpinner = uispinner(app.RotationSpinnerPanel);
            app.RotatexdegreesSpinner.Step = 0.1;
            app.RotatexdegreesSpinner.Limits = [-360 360];
            app.RotatexdegreesSpinner.Enable = 'off';
            app.RotateydegreesSpinnerLabel = uilabel(app.RotationSpinnerPanel);
            app.RotateydegreesSpinnerLabel.Text = 'Rotate y (degrees)';
            app.RotateydegreesSpinner = uispinner(app.RotationSpinnerPanel);
            app.RotateydegreesSpinner.Step = 0.1;
            app.RotateydegreesSpinner.Limits = [-360 360];
            app.RotateydegreesSpinner.Enable = 'off';
            app.RotatezdegreesSpinnerLabel = uilabel(app.RotationSpinnerPanel);
            app.RotatezdegreesSpinnerLabel.Text = 'Rotate z (degrees)';
            app.RotatezdegreesSpinner = uispinner(app.RotationSpinnerPanel);
            app.RotatezdegreesSpinner.Step = 0.1;
            app.RotatezdegreesSpinner.Limits = [-360 360];
            app.RotatezdegreesSpinner.Enable = 'off';
            app.ClearTransformsButton = uibutton(app.TransformPanelGridLayout, 'push');
            app.ClearTransformsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTransformsButtonPushed, true);
            app.ClearTransformsButton.Icon = 'img_erase_22px.png';
            app.ClearTransformsButton.Enable = 'off';
            app.ClearTransformsButton.Text = 'Clear Transforms';
            app.ApplyTransformstoModelButton = uibutton(app.TransformPanelGridLayout, 'push');
            app.ApplyTransformstoModelButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTransformstoModelButtonPushed, true);
            app.ApplyTransformstoModelButton.Icon = 'img_transform_22px.png';
            app.ApplyTransformstoModelButton.Enable = 'off';
            app.ApplyTransformstoModelButton.Text = 'Apply Transforms to Model';
            app.TranslationGridPanel = uigridlayout(app.TransformPanelGridLayout);
            app.TranslateHeader = uigridlayout(app.TranslationGridPanel);
            app.TranslateMoveLabel = uilabel(app.TranslateHeader);
            app.TranslateMoveLabel.Text = 'Translate (Move)';
            app.Image2 = uiimage(app.TranslateHeader);
            app.Image2.ImageSource = 'img_translate_22px.png';
            app.TranslatethisModelCheckBox = uicheckbox(app.TranslationGridPanel);
            app.TranslatethisModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @TranslatethisModelCheckBoxValueChanged, true);
            app.TranslatethisModelCheckBox.Text = 'Translate this Model';
            app.TranslationSpinnerPanel = uipanel(app.TranslationGridPanel);
            app.TranslatexmmSpinnerLabel = uilabel(app.TranslationSpinnerPanel);
            app.TranslatexmmSpinnerLabel.Text = 'Translate x (mm)';
            app.TranslateXSpinner = uispinner(app.TranslationSpinnerPanel);
            app.TranslateXSpinner.Step = 0.1;
            app.TranslateXSpinner.Limits = [-360 360];
            app.TranslateXSpinner.Enable = 'off';
            app.TranslateymmSpinnerLabel = uilabel(app.TranslationSpinnerPanel);
            app.TranslateymmSpinnerLabel.Text = 'Translate y (mm)';
            app.TranslateYSpinner = uispinner(app.TranslationSpinnerPanel);
            app.TranslateYSpinner.Step = 0.1;
            app.TranslateYSpinner.Limits = [-360 360];
            app.TranslateYSpinner.Enable = 'off';
            app.TranslatezmmSpinnerLabel = uilabel(app.TranslationSpinnerPanel);
            app.TranslatezmmSpinnerLabel.Text = 'Translate z (mm)';
            app.TranslateZSpinner = uispinner(app.TranslationSpinnerPanel);
            app.TranslateZSpinner.Step = 0.1;
            app.TranslateZSpinner.Limits = [-360 360];
            app.TranslateZSpinner.Enable = 'off';
            app.GridLayout12 = uigridlayout(app.TransformPanelGridLayout);
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.Image4 = uiimage(app.GridLayout13);
            app.Image4.ImageSource = 'img_scale_22px.png';
            app.ScaleResizeLabel = uilabel(app.GridLayout13);
            app.ScaleResizeLabel.Text = 'Scale (Resize)';
            app.ScaleSpinnerPanel = uipanel(app.GridLayout12);
            app.ScaleLabel_2 = uilabel(app.ScaleSpinnerPanel);
            app.ScaleLabel_2.Text = 'Scale (%)';
            app.ScaleSpinner = uispinner(app.ScaleSpinnerPanel);
            app.ScaleSpinner.Step = 0.1;
            app.ScaleSpinner.Limits = [0.1 Inf];
            app.ScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @ScaleSpinnerValueChanged, true);
            app.ScaleSpinner.Value = 100;
            app.Image11 = uiimage(app.TransformPanelGridLayout);
            app.Image11.ImageSource = 'img_planeConventionsGraphics.png';
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_2 = uilabel(app.TransformPanelGridLayout);
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_2.Text = 'Transform the model for slicing using the toolset below if necessary:';
            app.ConfigurePanel = uipanel(app.Module2AdaptiveFlatLayerSlicerUIFigure);
            app.ConfigurePanel.Title = 'Configure';
            app.GridLayout5_3 = uigridlayout(app.ConfigurePanel);
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_5 = uilabel(app.GridLayout5_3);
            app.SelectOpenSTLandbrowsetotheSTLfiletobeimportedslicedLabel_5.Text = 'Configure the parameters for slicing if necessary:';
            app.GridLayout14 = uigridlayout(app.GridLayout5_3);
            app.GridLayout15 = uigridlayout(app.GridLayout14);
            app.PathSelectionLabel = uilabel(app.GridLayout15);
            app.PathSelectionLabel.Text = 'Path Selection';
            app.Image7 = uiimage(app.GridLayout15);
            app.Image7.ImageSource = 'img_pathSelection_22px.png';
            app.Label_2 = uilabel(app.GridLayout14);
            app.Label_2.Text = 'Full Raster Path generates Infill inside the model. Edge Path generates the outline of each layer, with a hollow interior.';
            app.PathSelectionRadioGroup = uibuttongroup(app.GridLayout14);
            app.FullRasterPathButton = uiradiobutton(app.PathSelectionRadioGroup);
            app.FullRasterPathButton.Enable = 'off';
            app.FullRasterPathButton.Text = 'Full Raster Path';
            app.EdgePathOnlyButton = uiradiobutton(app.PathSelectionRadioGroup);
            app.EdgePathOnlyButton.Text = 'Edge Path Only';
            app.EdgePathOnlyButton.Value = true;
            app.Image10 = uiimage(app.GridLayout14);
            app.Image10.ImageSource = 'img_pathOptionsGraphics.png';
            app.AdaptiveSlicerLayout = uigridlayout(app.GridLayout5_3);
            app.GridLayout18 = uigridlayout(app.AdaptiveSlicerLayout);
            app.SlicerSelectionLabel = uilabel(app.GridLayout18);
            app.SlicerSelectionLabel.Text = 'Slicer Selection';
            app.Image6 = uiimage(app.GridLayout18);
            app.Image6.ImageSource = 'img_layers_22px.png';
            app.SlicerSelectionRadioGroup = uibuttongroup(app.AdaptiveSlicerLayout);
            app.SlicerSelectionRadioGroup.SelectionChangedFcn = createCallbackFcn(app, @SlicerSelectionRadioGroupSelectionChanged, true);
            app.UniformSlicingButton = uiradiobutton(app.SlicerSelectionRadioGroup);
            app.UniformSlicingButton.Text = 'Uniform Slicing';
            app.UniformSlicingButton.Value = true;
            app.AdaptiveSlicingButton = uiradiobutton(app.SlicerSelectionRadioGroup);
            app.AdaptiveSlicingButton.Text = 'Adaptive Slicing';
            app.Label = uilabel(app.AdaptiveSlicerLayout);
            app.Label.Text = 'Uniform Slicing uses a fixed layer thickness throughout the print. Adaptive Slicing intelligently adjusts layer thickness throughout the print to reduce print time, with better resolution on fine details';
            app.Panel = uipanel(app.AdaptiveSlicerLayout);
            app.LayerThicknessmmSpinnerLabel = uilabel(app.Panel);
            app.LayerThicknessmmSpinnerLabel.Text = 'Layer Thickness (mm)';
            app.LayerThicknessmmSpinner = uispinner(app.Panel);
            app.LayerThicknessmmSpinner.Step = 0.01;
            app.LayerThicknessmmSpinner.Limits = [0 Inf];
            app.LayerThicknessmmSpinner.ValueChangedFcn = createCallbackFcn(app, @LayerThicknessmmSpinnerValueChanged, true);
            app.LayerThicknessmmSpinner.Value = 0.2;
            app.Image9 = uiimage(app.AdaptiveSlicerLayout);
            app.Image9.ImageSource = 'img_slicerOptionsGraphics.png';
            app.GotoStep4SliceButton = uibutton(app.GridLayout5_3, 'push');
            app.GotoStep4SliceButton.ButtonPushedFcn = createCallbackFcn(app, @GotoStep4SliceButtonPushed, true);
            app.GotoStep4SliceButton.Icon = 'img_arrowRight_16px.png';
            app.GotoStep4SliceButton.Text = 'Go to Step 4: Slice';
            app.SlicePanel = uipanel(app.Module2AdaptiveFlatLayerSlicerUIFigure);
            app.SlicePanel.Title = 'Slice';
            app.GridLayout5_4 = uigridlayout(app.SlicePanel);
            app.GridLayout6_4 = uigridlayout(app.GridLayout5_4);
            app.ResetViewButton = uibutton(app.GridLayout6_4, 'push');
            app.ResetViewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetViewButtonPushed, true);
            app.ResetViewButton.Icon = 'img_undo_22px.png';
            app.ResetViewButton.Enable = 'off';
            app.ResetViewButton.Text = 'Reset View';
            app.ExportDataButton = uibutton(app.GridLayout6_4, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Icon = 'img_save_22px.png';
            app.ExportDataButton.Enable = 'off';
            app.ExportDataButton.Text = 'Export to .csv...';
            app.SelectedFileNameLabel_4 = uilabel(app.GridLayout5_4);
            app.SelectedFileNameLabel_4.Text = 'Slicer is Ready';
            app.SliceModelButton = uibutton(app.GridLayout5_4, 'push');
            app.SliceModelButton.ButtonPushedFcn = createCallbackFcn(app, @SliceModelButtonPushed, true);
            app.SliceModelButton.Icon = 'img_run_22px.png';
            app.SliceModelButton.Text = 'Slice Model';
            app.GridLayout16 = uigridlayout(app.GridLayout5_4);
            app.GridLayout17 = uigridlayout(app.GridLayout16);
            app.ExploreLayersLabel = uilabel(app.GridLayout17);
            app.ExploreLayersLabel.Text = 'Explore Layers';
            app.Image5 = uiimage(app.GridLayout17);
            app.Image5.ImageSource = 'img_find_22px.png';
            app.Panel_2 = uipanel(app.GridLayout16);
            app.LayerLabel = uilabel(app.Panel_2);
            app.LayerLabel.Enable = 'off';
            app.LayerLabel.Text = 'Layer';
            app.LayerViewSlider = uislider(app.Panel_2);
            app.LayerViewSlider.Limits = [1 2];
            app.LayerViewSlider.ValueChangedFcn = createCallbackFcn(app, @LayerViewSliderValueChanged, true);
            app.LayerViewSlider.ValueChangingFcn = createCallbackFcn(app, @LayerViewSliderValueChanging, true);
            app.LayerViewSlider.Enable = 'off';
            app.LayerViewSlider.Value = 1;
            app.LayerViewNumberLabel = uilabel(app.Panel_2);
            app.LayerViewNumberLabel.Text = {'1'; ''};
            app.IsolateLayerinPreviewCheckBox = uicheckbox(app.GridLayout16);
            app.IsolateLayerinPreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsolateLayerinPreviewCheckBoxValueChanged, true);
            app.IsolateLayerinPreviewCheckBox.Enable = 'off';
            app.IsolateLayerinPreviewCheckBox.Text = 'Isolate Layer in Preview';
            app.SliceHeroLabel = uilabel(app.GridLayout5_4);
            app.SliceHeroLabel.Text = 'Select "Slice Model" to update the Slicer Path, then use the tools to view and export the Slicer Path.';
            app.AnimationGrid = uigridlayout(app.GridLayout5_4);
            app.AnimationHero = uigridlayout(app.AnimationGrid);
            app.AnimationTitle = uilabel(app.AnimationHero);
            app.AnimationTitle.Text = 'Animate';
            app.AnimationImg = uiimage(app.AnimationHero);
            app.AnimationImg.ImageSource = 'img_greenWand_22px.png';
            app.AnimationSpeedPanel = uipanel(app.AnimationGrid);
            app.StepSpeedsecLabel = uilabel(app.AnimationSpeedPanel);
            app.StepSpeedsecLabel.Enable = 'off';
            app.StepSpeedsecLabel.Text = 'Step Speed (sec)';
            app.AnimationSpeedSpinner = uispinner(app.AnimationSpeedPanel);
            app.AnimationSpeedSpinner.Step = 0.1;
            app.AnimationSpeedSpinner.Limits = [0.1 Inf];
            app.AnimationSpeedSpinner.Enable = 'off';
            app.AnimationSpeedSpinner.Value = 0.5;
            app.AnimateButton = uibutton(app.AnimationGrid, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Icon = 'img_animate_22px.png';
            app.AnimateButton.Enable = 'off';
            app.AnimateButton.Text = 'Animate...';
            app.AnimationOptionRadioControlGroup = uibuttongroup(app.AnimationGrid);
            app.AnimateSelectedSliceButton = uiradiobutton(app.AnimationOptionRadioControlGroup);
            app.AnimateSelectedSliceButton.Enable = 'off';
            app.AnimateSelectedSliceButton.Text = 'Animate Selected Slice';
            app.AnimateSelectedSliceButton.Value = true;
            app.AnimateuptoSelectedSliceButton = uiradiobutton(app.AnimationOptionRadioControlGroup);
            app.AnimateuptoSelectedSliceButton.Enable = 'off';
            app.AnimateuptoSelectedSliceButton.Text = 'Animate up to Selected Slice';
            app.AnimateentireModelButton = uiradiobutton(app.AnimationOptionRadioControlGroup);
            app.AnimateentireModelButton.Enable = 'off';
            app.AnimateentireModelButton.Text = 'Animate entire Model';
            app.DefinePanel = uipanel(app.Module2AdaptiveFlatLayerSlicerUIFigure);
            app.DefinePanel.Title = 'Define';
            app.DefinePanelLayout = uigridlayout(app.DefinePanel);
            app.DefinethepropertiesoftheBezierCurvetogenerateLabel = uilabel(app.DefinePanelLayout);
            app.DefinethepropertiesoftheBezierCurvetogenerateLabel.Text = 'Define the properties of the Bezier Curve to generate.';
            app.GotoStep2TransformButton = uibutton(app.DefinePanelLayout, 'push');
            app.GotoStep2TransformButton.ButtonPushedFcn = createCallbackFcn(app, @GotoStep2TransformButtonPushed, true);
            app.GotoStep2TransformButton.Icon = 'img_arrowRight_16px.png';
            app.GotoStep2TransformButton.Enable = 'off';
            app.GotoStep2TransformButton.Text = 'Go to Step 2: Transform';
            app.AutoGenXYRadios = uigridlayout(app.DefinePanelLayout);
            app.GridLayout20 = uigridlayout(app.AutoGenXYRadios);
            app.Image8 = uiimage(app.GridLayout20);
            app.Image8.ImageSource = 'img_greenWand_22px.png';
            app.GenerateUniformXYPointsLabel = uilabel(app.GridLayout20);
            app.GenerateUniformXYPointsLabel.Text = 'Generate Uniform X & Y Points';
            app.AutoGenXYRadioGroup = uibuttongroup(app.AutoGenXYRadios);
            app.AutoGenerateButton = uiradiobutton(app.AutoGenXYRadioGroup);
            app.AutoGenerateButton.Text = 'Auto-Generate';
            app.AutoGenerateButton.Value = true;
            app.ManuallyDefineBxByButton = uiradiobutton(app.AutoGenXYRadioGroup);
            app.ManuallyDefineBxByButton.Text = 'Manually Define Bx & By';
            app.SquareMatrixSizeSpinnerPanel = uipanel(app.DefinePanelLayout);
            app.SquareMatrixSizeSpinnerLabel = uilabel(app.SquareMatrixSizeSpinnerPanel);
            app.SquareMatrixSizeSpinnerLabel.Text = 'Square Matrix Size';
            app.SquareMatrixSizeSpinner = uispinner(app.SquareMatrixSizeSpinnerPanel);
            app.SquareMatrixSizeSpinner.Limits = [2 Inf];
            app.SquareMatrixSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @SquareMatrixSizeSpinnerValueChanged, true);
            app.SquareMatrixSizeSpinner.Value = 2;
            app.bZTableLayout = uigridlayout(app.DefinePanelLayout);
            app.GridLayout21 = uigridlayout(app.bZTableLayout);
            app.Image12 = uiimage(app.GridLayout21);
            app.Image12.ImageSource = 'img_table_22px.png';
            app.DefineBzLabel = uilabel(app.GridLayout21);
            app.DefineBzLabel.Text = 'Define Bz';
            app.bZTable = uitable(app.Module2AdaptiveFlatLayerSlicerUIFigure);
            app.bZTable.ColumnName = '';
            app.bZTable.RowName = {};
            app.bZTable.CellEditCallback = createCallbackFcn(app, @bZTableCellEdit, true);
            % Show the figure after all components are created
            app.Module2AdaptiveFlatLayerSlicerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module4GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Module2AdaptiveFlatLayerSlicerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Module2AdaptiveFlatLayerSlicerUIFigure)
        end
    end
end